classdef oguzalp_cetin < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                        matlab.ui.Figure
		TemperatureEditField            matlab.ui.control.NumericEditField
		TemperatureEditFieldLabel       matlab.ui.control.Label
		CurrentIterationEditField       matlab.ui.control.NumericEditField
		CurrentIterationLabel           matlab.ui.control.Label
		StopButton                      matlab.ui.control.Button
		YEditField                      matlab.ui.control.NumericEditField
		YEditFieldLabel                 matlab.ui.control.Label
		XEditField                      matlab.ui.control.NumericEditField
		XEditFieldLabel                 matlab.ui.control.Label
		MonitoringNodeLabel             matlab.ui.control.Label
		Panel                           matlab.ui.container.Panel
		OuzalpetinLabel                 matlab.ui.control.Label
		AuthorSwitch                    matlab.ui.control.ToggleSwitch
		ExitStatusTextArea              matlab.ui.control.TextArea
		ExitStatusTextAreaLabel         matlab.ui.control.Label
		MaxiterationsEditField          matlab.ui.control.NumericEditField
		MaxiterationsEditFieldLabel     matlab.ui.control.Label
		hEditField                      matlab.ui.control.NumericEditField
		hEditFieldLabel                 matlab.ui.control.Label
		MaterialDropDown                matlab.ui.control.DropDown
		MaterialDropDownLabel           matlab.ui.control.Label
		HeatGenEditField                matlab.ui.control.NumericEditField
		HeatGenEditFieldLabel           matlab.ui.control.Label
		tEditField                      matlab.ui.control.NumericEditField
		tEditFieldLabel                 matlab.ui.control.Label
		T_guessEditField                matlab.ui.control.NumericEditField
		T_guessEditFieldLabel           matlab.ui.control.Label
		RestoreDefaultsButton           matlab.ui.control.Button
		run_button                      matlab.ui.control.Button
		InProcessLamp                   matlab.ui.control.Lamp
		InProcessLampLabel              matlab.ui.control.Label
		BoundaryConditionsListBox       matlab.ui.control.ListBox
		BoundaryConditionsListBoxLabel  matlab.ui.control.Label
		kEditField                      matlab.ui.control.NumericEditField
		kEditFieldLabel                 matlab.ui.control.Label
		nyEditField                     matlab.ui.control.NumericEditField
		nyEditFieldLabel                matlab.ui.control.Label
		LyEditField                     matlab.ui.control.NumericEditField
		LyEditFieldLabel                matlab.ui.control.Label
		nxEditField                     matlab.ui.control.NumericEditField
		nxEditFieldLabel                matlab.ui.control.Label
		LxEditField                     matlab.ui.control.NumericEditField
		LxEditFieldLabel                matlab.ui.control.Label
		BoundaryButtonGroupBottom       matlab.ui.container.ButtonGroup
		ValueEditFieldBottom            matlab.ui.control.NumericEditField
		ValueEditField_4Label           matlab.ui.control.Label
		HeatLossButtonBottom            matlab.ui.control.RadioButton
		InsulatedButtonBottom           matlab.ui.control.RadioButton
		HeatFluxButtonBottom            matlab.ui.control.RadioButton
		ConstantTemperatureButtonBottom  matlab.ui.control.RadioButton
		BoundaryButtonGroupTop          matlab.ui.container.ButtonGroup
		ValueEditFieldTop               matlab.ui.control.NumericEditField
		ValueEditField_3Label           matlab.ui.control.Label
		HeatLossButtonTop               matlab.ui.control.RadioButton
		InsulatedButtonTop              matlab.ui.control.RadioButton
		HeatFluxButtonTop               matlab.ui.control.RadioButton
		ConstantTemperatureButtonTop    matlab.ui.control.RadioButton
		BoundaryButtonGroupLeft         matlab.ui.container.ButtonGroup
		ValueEditFieldLeft              matlab.ui.control.NumericEditField
		ValueEditFieldLabel             matlab.ui.control.Label
		HeatLossButtonLeft              matlab.ui.control.RadioButton
		InsulatedButtonLeft             matlab.ui.control.RadioButton
		HeatFluxButtonLeft              matlab.ui.control.RadioButton
		ConstantTemperatureButtonLeft   matlab.ui.control.RadioButton
		BoundaryButtonGroupRight        matlab.ui.container.ButtonGroup
		ValueEditFieldRight             matlab.ui.control.NumericEditField
		ValueEditField_5Label           matlab.ui.control.Label
		HeatLossButtonRight             matlab.ui.control.RadioButton
		InsulatedButtonRight            matlab.ui.control.RadioButton
		HeatFluxButtonRight             matlab.ui.control.RadioButton
		ConstantTemperatureButtonRight  matlab.ui.control.RadioButton
		UIAxes3                         matlab.ui.control.UIAxes
		UIAxes2                         matlab.ui.control.UIAxes
		UIAxes                          matlab.ui.control.UIAxes
	end

	methods (Access = private)

		function	[A, C_prime] = get_coeffs(~, a_n, a_s, a_e, a_w, a_p, T, S_u, idx, sweep)
			nx = size(a_p, 2);
			ny = size(a_p, 1);
			if sweep == 'h'
				alpha = a_n;
				beta = a_s;
				D = a_p;
				C = zeros(ny, 1);
				A = zeros(ny, 1);
				C_prime = zeros(ny, 1);
				for j = 1 : ny
					if idx == 1
						C(j) = a_e(j, idx) * T(j, idx + 1) + S_u(j, idx);
					elseif idx == nx
						C(j) = a_w(j, idx) * T(j, idx - 1) + S_u(j, idx);
					else
						C(j) = a_w(j, idx) * T(j, idx - 1) + a_e(j, idx) * T(j, idx + 1) + S_u(j, idx);
					end
				end
				for j = 1 : ny
					if j == 1
						A(j) = alpha(j, idx) / D(j, idx);
						C_prime(j) = C(j) / D(j, idx);
					else
						A(j) = alpha(j, idx) / (D(j, idx) - beta(j, idx) * A(j - 1));
						C_prime(j) = (beta(j, idx) * C_prime(j - 1) + C(j)) / (D(j, idx) - beta(j, idx) * A(j - 1));
					end
				end
			elseif sweep == 'v'
				alpha = a_e;
				beta = a_w;
				D = a_p;
				C = zeros(nx, 1);
				A = zeros(nx, 1);
				C_prime = zeros(nx, 1);
				for i = 1 : nx
					if idx == 1
						C(i) = a_n(idx, i) * T(idx + 1, i) + S_u(idx, i);
					elseif idx == ny
						C(i) = a_s(idx, i) * T(idx - 1, i) + S_u(idx, i);
					else
						C(i) = a_s(idx, i) * T(idx - 1, i) + a_n(idx, i) * T(idx + 1, i) + S_u(idx, i);
					end
				end
				for i = 1 : nx
					if i == 1
						A(i) = alpha(idx, i) / D(idx, i);
						C_prime(i) = C(i) / D(idx, i);
					else
						A(i) = alpha(idx, i) / (D(idx, i) - beta(idx, i) * A(i - 1));
						C_prime(i) = (beta(idx, i) * C_prime(i - 1) + C(i)) / (D(idx, i) - beta(idx, i) * A(i - 1));
					end
				end
			end
		end

		function	T_new = get_T_line(~, A, C_prime, sweep)
			if sweep == 'h'
				T_new = zeros(size(A, 1), 1);
				ny = size(A, 1);
				for j = ny : -1 : 1
					if j == ny
						T_new(j) = C_prime(j);
					else
						T_new(j) = A(j) * T_new(j + 1) + C_prime(j);
					end
				end
			elseif sweep == 'v'
				T_new = zeros(1, size(A, 1));
				nx = size(A, 1);
				for i = nx : -1 : 1
					if i == nx
						T_new(i) = C_prime(i);
					else
						T_new(i) = A(i) * T_new(i + 1) + C_prime(i);
					end
				end
			end
		end

	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			RestoreDefaultsButtonPushed(app, []);
		end

		% Button pushed function: run_button
		function run_buttonButtonPushed(app, event)
			if (app.nxEditField.Value * app.nyEditField.Value < 1500 ...
					|| app.nxEditField.Value * app.nyEditField.Value > 10000)
				warndlg('Number of nodes must be between 1500 and 10000 !','Warning');
				return ;
			end
			app.run_button.Enable = "off";
			app.InProcessLamp.Color = [0, 1, 0];
			app.ExitStatusTextArea.Value = "";
			cla(app.UIAxes, 'reset');
			cla(app.UIAxes2, 'reset');
			cla(app.UIAxes3, 'reset');
			title(app.UIAxes, 'Temperature Map');
			title(app.UIAxes2, 'Error');
			set(app.UIAxes2,'YScale','log');
			title(app.UIAxes3, 'Temperature of Monitoring Node');
			grid(app.UIAxes3, 'on');
			hold(app.UIAxes2, "on");
			hold(app.UIAxes3, "on");

			nx = app.nxEditField.Value;
			ny = app.nyEditField.Value;
			dx = app.LxEditField.Value / nx;
			dy = app.LyEditField.Value / ny;
			t = app.tEditField.Value;
			h_gen = app.HeatGenEditField.Value * dx * dy * t;
			k = app.kEditField.Value;
			h = app.hEditField.Value;
			A_we = dy * t;
			A_ns = dx * t;
			a_we = k * A_we / dx;
			a_ns = k * A_ns / dy;
			T = ones(ny, nx) * app.T_guessEditField.Value;
			S_u = zeros(ny, nx);
			S_p = zeros(ny, nx);

			bc.type.left = app.BoundaryButtonGroupLeft.SelectedObject.Text;
			bc.val.left = app.ValueEditFieldLeft.Value;
			S_uw = zeros(ny, nx);
			S_pw = zeros(ny, nx);
			q = 0;
			h_loss = 0;
			T_c = 0;
			if (strcmp(bc.type.left, 'Constant Temperature'))
				T_c = bc.val.left;
				S_pw(:, 1) = -2 * k * A_we / dx;
			elseif (strcmp(bc.type.left, 'Heat Flux'))
				q = bc.val.left;
			elseif (strcmp(bc.type.left, 'Heat Loss'))
				T_f = bc.val.left;
				h_loss = h * A_we * T_f;
				S_pw(:, 1) = -2 * h * A_we;
			end
			S_uw(:, 1) = q * A_we + h_gen + h_loss + 2 * k * A_we *  T_c / dx;
			S_u(:, 1) = S_u(:, 1) + S_uw(:, 1);
			S_p(:, 1) = S_p(:, 1) + S_pw(:, 1);

			bc.type.right = app.BoundaryButtonGroupRight.SelectedObject.Text;
			bc.val.right = app.ValueEditFieldRight.Value;
			S_ue = zeros(ny, nx);
			S_pe = zeros(ny, nx);
			q = 0;
			h_loss = 0;
			T_c = 0;
			if (strcmp(bc.type.right, 'Constant Temperature'))
				T_c = bc.val.right;
				S_pe(:, nx) = -2 * k * A_we / dx;
			elseif (strcmp(bc.type.right, 'Heat Flux'))
				q = bc.val.right;
			elseif (strcmp(bc.type.right, 'Heat Loss'))
				T_f = bc.val.right;
				h_loss = h * A_we * T_f;
				S_pe(:, nx) = -2 * h * A_we;
			end
			S_ue(:, nx) = q * A_we + h_gen + h_loss + 2 * k * A_we * T_c / dx;
			S_u(:, nx) = S_u(:, nx) + S_ue(:, nx);
			S_p(:, nx) = S_p(:, nx) + S_pe(:, nx);

			bc.type.top = app.BoundaryButtonGroupTop.SelectedObject.Text;
			bc.val.top = app.ValueEditFieldTop.Value;
			S_un = zeros(ny, nx);
			S_pn = zeros(ny, nx);
			q = 0;
			h_loss = 0;
			T_c = 0;
			if (strcmp(bc.type.top, 'Constant Temperature'))
				T_c = bc.val.top;
				S_pn(ny, :) = -2 * k * A_ns / dy;
			elseif (strcmp(bc.type.top, 'Heat Flux'))
				q = bc.val.top;
			elseif (strcmp(bc.type.top, 'Heat Loss'))
				T_f = bc.val.top;
				h_loss = h * A_ns * T_f;
				S_pn(ny, :) = -2 * h * A_ns;
			end
			S_un(ny, :) = q * A_ns + h_gen + h_loss + 2 * k * A_ns * T_c / dy;
			S_u(ny, :) = S_u(ny, :) + S_un(ny, :);
			S_p(ny, :) = S_pn(ny, :);

			bc.type.bottom = app.BoundaryButtonGroupBottom.SelectedObject.Text;
			bc.val.bottom = app.ValueEditFieldBottom.Value;
			S_us = zeros(ny, nx);
			S_ps = zeros(ny, nx);
			q = 0;
			h_loss = 0;
			T_c = 0;
			if (strcmp(bc.type.bottom, 'Constant Temperature'))
				T_c = bc.val.bottom;
				S_ps(1, :) = -2 * k * A_ns / dy;
			elseif (strcmp(bc.type.bottom, 'Heat Flux'))
				q = bc.val.bottom;
			elseif (strcmp(bc.type.bottom, 'Heat Loss'))
				T_f = bc.val.bottom;
				h_loss = h * A_ns * T_f;
				S_ps(1, :) = -2 * h * A_ns;
			end
			S_us(1, :) = q * A_ns + h_gen + h_loss + 2 * k * A_ns * T_c / dy;
			S_u(1, :) = S_u(1, :) + S_us(1, :);
			S_p(1, :) = S_p(1, :) + S_ps(1 ,:);

			a_w = zeros(ny, nx) + a_we;
			a_w(:, 1) = 0;
			a_e = zeros(ny, nx) + a_we;
			a_e(:, nx) = 0;
			a_n = zeros(ny, nx) + a_ns;
			a_n(ny, :) = 0;
			a_s = zeros(ny, nx) + a_ns;
			a_s(1, :) = 0;
			a_p =  a_w + a_e + a_n + a_s - S_p;

			x = linspace(0, app.LxEditField.Value, nx);
			y = linspace(0, app.LyEditField.Value, ny);
			[X, Y] = meshgrid(x, y);
			monitor = animatedline(app.UIAxes3, 'Color', 'b', 'LineWidth', 2);
			addpoints(monitor, 0, app.T_guessEditField.Value);
			iter = 0;
			tol = 1e-4;
			max_iter = app.MaxiterationsEditField.Value;
			err = 150418031;
			while (err > tol)
				T_old = T;
				if (iter == max_iter || strcmp(app.StopButton.Enable, "off"))
					break ;
				end
				for i = 1 : nx
					[A, C_prime] = get_coeffs(app, a_n, a_s, a_e, a_w, a_p, T, S_u, i, 'h');
					T(:, i) = get_T_line(app, A, C_prime, 'h');
				end
				for j = 1 : ny
					[A, C_prime] = get_coeffs(app, a_n, a_s, a_e, a_w, a_p, T, S_u, j, 'v');
					T(j, :) = get_T_line(app, A, C_prime, 'v');
				end
				iter = iter + 1;
				if ~(mod(iter - 1, 8))
					contourf(app.UIAxes, X, Y, T);
					colorbar(app.UIAxes);
				end
				app.CurrentIterationEditField.Value = iter;
				app.TemperatureEditField.Value = T(app.YEditField.Value, app.XEditField.Value);
				addpoints(monitor, iter, app.TemperatureEditField.Value);
				err = norm(T - T_old, 'inf');
				curve = animatedline(app.UIAxes2, "Color", 'r', 'Marker','*');
				addpoints(curve, iter, err);
				drawnow;
			end
			if iter == max_iter
				app.ExitStatusTextArea.Value = 'Reached To Maximum Iterations';
			elseif strcmp(app.StopButton.Enable, "off")
				app.ExitStatusTextArea.Value = 'Simulation stopped manually.';
			else
				app.ExitStatusTextArea.Value = sprintf('Solution Converged at iteration %s.\n', num2str(iter));
			end
			contourf(app.UIAxes, X, Y, T);
			colorbar(app.UIAxes);
			hold(app.UIAxes2, "off");
			hold(app.UIAxes3, "off");
			pause(1);

			app.InProcessLamp.Color = [1, 0, 0];
			app.AuthorSwitch.Value = 'Show Author';
			AuthorSwitchValueChanged(app, []);
			app.StopButton.Enable = "on";
			app.run_button.Enable = "on";
		end

		% Value changed function: BoundaryConditionsListBox
		function BoundaryConditionsListBoxValueChanged(app, event)
			value = app.BoundaryConditionsListBox.Value;
			if strcmp(value,'Left')
				app.BoundaryButtonGroupLeft.Visible = "on";
			else
				app.BoundaryButtonGroupLeft.Visible = "off";
			end
			if strcmp(value,'Right')
				app.BoundaryButtonGroupRight.Visible = "on";
			else
				app.BoundaryButtonGroupRight.Visible = "off";
			end
			if strcmp(value,'Top')
				app.BoundaryButtonGroupTop.Visible = "on";
			else
				app.BoundaryButtonGroupTop.Visible = "off";
			end
			if strcmp(value,'Bottom')
				app.BoundaryButtonGroupBottom.Visible = "on";
			else
				app.BoundaryButtonGroupBottom.Visible = "off";
			end
		end

		% Selection changed function: BoundaryButtonGroupBottom, 
		% ...and 3 other components
		function BoundaryButtonGroupLeftSelectionChanged(app, event)
			if app.InsulatedButtonLeft.Value
				app.ValueEditFieldLeft.Visible = "off";
			else
				app.ValueEditFieldLeft.Visible = "on";
			end
			if app.InsulatedButtonRight.Value
				app.ValueEditFieldRight.Visible = "off";
			else
				app.ValueEditFieldRight.Visible = "on";
			end
			if app.InsulatedButtonTop.Value
				app.ValueEditFieldTop.Visible = "off";
			else
				app.ValueEditFieldTop.Visible = "on";
			end
			if app.InsulatedButtonBottom.Value
				app.ValueEditFieldBottom.Visible = "off";
			else
				app.ValueEditFieldBottom.Visible = "on";
			end
		end

		% Button pushed function: RestoreDefaultsButton
		function RestoreDefaultsButtonPushed(app, event)
			app.nxEditField.Value = 30;
			app.nyEditField.Value = 50;
			app.LxEditField.Value = 0.3;
			app.LyEditField.Value = 0.4;
			app.tEditField.Value = 0.01;
			app.kEditField.Value = 1000;
			app.kEditField.Visible = "on";
			app.kEditFieldLabel.Visible = "on";
			app.MaterialDropDown.Value = 'Other';
			app.InsulatedButtonBottom.Value = 1;
			app.InsulatedButtonRight.Value = 1;
			app.ConstantTemperatureButtonTop.Value = 1;
			app.ValueEditFieldTop.Value = 100;
			app.HeatFluxButtonLeft.Value = 1;
			app.ValueEditFieldLeft.Value = 500000;
			app.T_guessEditField.Value = 0;
			app.MaxiterationsEditField.Value = 500;
			app.XEditField.Value = 1;
			app.YEditField.Value = 1;
		end

		% Value changed function: MaterialDropDown
		function MaterialDropDownValueChanged(app, event)
			value = app.MaterialDropDown.Value;
			if strcmp(value, 'Other')
				app.kEditField.Visible = "on";
				app.kEditFieldLabel.Visible = "on";
			else
				app.kEditField.Visible = "off";
				app.kEditFieldLabel.Visible = "off";
			end
			if strcmp(value, 'Iron')
				app.kEditField.Value = 80.2;
			elseif strcmp(value, 'Copper')
				app.kEditField.Value = 401;
			elseif strcmp(value, 'Aluminum')
				app.kEditField.Value = 234;
			end
		end

		% Value changed function: AuthorSwitch
		function AuthorSwitchValueChanged(app, event)
			value = app.AuthorSwitch.Value;
			if strcmp(value, 'Show Author')
				app.Panel.Visible = "on";
			else
				app.Panel.Visible = "off";
			end
		end

		% Button pushed function: StopButton
		function StopButtonPushed(app, event)
			app.StopButton.Enable = "off";
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIAxes = uiaxes(app.UIFigure);
			title(app.UIAxes, 'Temperature Map')
			xlabel(app.UIAxes, 'X')
			ylabel(app.UIAxes, 'Y')
			zlabel(app.UIAxes, 'Z')
			app.UIAxes2 = uiaxes(app.UIFigure);
			title(app.UIAxes2, 'Error ')
			xlabel(app.UIAxes2, 'X')
			ylabel(app.UIAxes2, 'Y')
			zlabel(app.UIAxes2, 'Z')
			app.UIAxes3 = uiaxes(app.UIFigure);
			title(app.UIAxes3, 'Title')
			xlabel(app.UIAxes3, 'X')
			ylabel(app.UIAxes3, 'Y')
			zlabel(app.UIAxes3, 'Z')
			app.BoundaryButtonGroupRight = uibuttongroup(app.UIFigure);
			app.BoundaryButtonGroupRight.SelectionChangedFcn = createCallbackFcn(app, @BoundaryButtonGroupLeftSelectionChanged, true);
			app.BoundaryButtonGroupRight.Title = 'Right Boundary';
			app.BoundaryButtonGroupRight.Visible = 'off';
			app.ConstantTemperatureButtonRight = uiradiobutton(app.BoundaryButtonGroupRight);
			app.ConstantTemperatureButtonRight.Text = 'Constant Temperature';
			app.ConstantTemperatureButtonRight.Value = true;
			app.HeatFluxButtonRight = uiradiobutton(app.BoundaryButtonGroupRight);
			app.HeatFluxButtonRight.Text = 'Heat Flux';
			app.InsulatedButtonRight = uiradiobutton(app.BoundaryButtonGroupRight);
			app.InsulatedButtonRight.Text = 'Insulated';
			app.HeatLossButtonRight = uiradiobutton(app.BoundaryButtonGroupRight);
			app.HeatLossButtonRight.Text = 'Heat Loss';
			app.ValueEditField_5Label = uilabel(app.BoundaryButtonGroupRight);
			app.ValueEditField_5Label.Text = 'Value';
			app.ValueEditFieldRight = uieditfield(app.BoundaryButtonGroupRight, 'numeric');
			app.BoundaryButtonGroupLeft = uibuttongroup(app.UIFigure);
			app.BoundaryButtonGroupLeft.SelectionChangedFcn = createCallbackFcn(app, @BoundaryButtonGroupLeftSelectionChanged, true);
			app.BoundaryButtonGroupLeft.Title = 'Left Boundary';
			app.ConstantTemperatureButtonLeft = uiradiobutton(app.BoundaryButtonGroupLeft);
			app.ConstantTemperatureButtonLeft.Text = 'Constant Temperature';
			app.ConstantTemperatureButtonLeft.Value = true;
			app.HeatFluxButtonLeft = uiradiobutton(app.BoundaryButtonGroupLeft);
			app.HeatFluxButtonLeft.Text = 'Heat Flux';
			app.InsulatedButtonLeft = uiradiobutton(app.BoundaryButtonGroupLeft);
			app.InsulatedButtonLeft.Text = 'Insulated';
			app.HeatLossButtonLeft = uiradiobutton(app.BoundaryButtonGroupLeft);
			app.HeatLossButtonLeft.Text = 'Heat Loss';
			app.ValueEditFieldLabel = uilabel(app.BoundaryButtonGroupLeft);
			app.ValueEditFieldLabel.Text = 'Value';
			app.ValueEditFieldLeft = uieditfield(app.BoundaryButtonGroupLeft, 'numeric');
			app.BoundaryButtonGroupTop = uibuttongroup(app.UIFigure);
			app.BoundaryButtonGroupTop.SelectionChangedFcn = createCallbackFcn(app, @BoundaryButtonGroupLeftSelectionChanged, true);
			app.BoundaryButtonGroupTop.Title = 'Top Boundary';
			app.BoundaryButtonGroupTop.Visible = 'off';
			app.ConstantTemperatureButtonTop = uiradiobutton(app.BoundaryButtonGroupTop);
			app.ConstantTemperatureButtonTop.Text = 'Constant Temperature';
			app.ConstantTemperatureButtonTop.Value = true;
			app.HeatFluxButtonTop = uiradiobutton(app.BoundaryButtonGroupTop);
			app.HeatFluxButtonTop.Text = 'Heat Flux';
			app.InsulatedButtonTop = uiradiobutton(app.BoundaryButtonGroupTop);
			app.InsulatedButtonTop.Text = 'Insulated';
			app.HeatLossButtonTop = uiradiobutton(app.BoundaryButtonGroupTop);
			app.HeatLossButtonTop.Text = 'Heat Loss';
			app.ValueEditField_3Label = uilabel(app.BoundaryButtonGroupTop);
			app.ValueEditField_3Label.Text = 'Value';
			app.ValueEditFieldTop = uieditfield(app.BoundaryButtonGroupTop, 'numeric');
			app.BoundaryButtonGroupBottom = uibuttongroup(app.UIFigure);
			app.BoundaryButtonGroupBottom.SelectionChangedFcn = createCallbackFcn(app, @BoundaryButtonGroupLeftSelectionChanged, true);
			app.BoundaryButtonGroupBottom.Title = 'Bottom Boundary';
			app.BoundaryButtonGroupBottom.Visible = 'off';
			app.ConstantTemperatureButtonBottom = uiradiobutton(app.BoundaryButtonGroupBottom);
			app.ConstantTemperatureButtonBottom.Text = 'Constant Temperature';
			app.ConstantTemperatureButtonBottom.Value = true;
			app.HeatFluxButtonBottom = uiradiobutton(app.BoundaryButtonGroupBottom);
			app.HeatFluxButtonBottom.Text = 'Heat Flux';
			app.InsulatedButtonBottom = uiradiobutton(app.BoundaryButtonGroupBottom);
			app.InsulatedButtonBottom.Text = 'Insulated';
			app.HeatLossButtonBottom = uiradiobutton(app.BoundaryButtonGroupBottom);
			app.HeatLossButtonBottom.Text = 'Heat Loss';
			app.ValueEditField_4Label = uilabel(app.BoundaryButtonGroupBottom);
			app.ValueEditField_4Label.Text = 'Value';
			app.ValueEditFieldBottom = uieditfield(app.BoundaryButtonGroupBottom, 'numeric');
			app.LxEditFieldLabel = uilabel(app.UIFigure);
			app.LxEditFieldLabel.Text = 'Lx';
			app.LxEditField = uieditfield(app.UIFigure, 'numeric');
			app.LxEditField.ValueDisplayFormat = '%11.4g [m]';
			app.nxEditFieldLabel = uilabel(app.UIFigure);
			app.nxEditFieldLabel.Text = 'nx';
			app.nxEditField = uieditfield(app.UIFigure, 'numeric');
			app.nxEditField.ValueDisplayFormat = '%11.4g [Nodes]';
			app.LyEditFieldLabel = uilabel(app.UIFigure);
			app.LyEditFieldLabel.Text = 'Ly';
			app.LyEditField = uieditfield(app.UIFigure, 'numeric');
			app.LyEditField.ValueDisplayFormat = '%11.4g [m]';
			app.nyEditFieldLabel = uilabel(app.UIFigure);
			app.nyEditFieldLabel.Text = 'ny';
			app.nyEditField = uieditfield(app.UIFigure, 'numeric');
			app.nyEditField.ValueDisplayFormat = '%11.4g [Nodes]';
			app.kEditFieldLabel = uilabel(app.UIFigure);
			app.kEditFieldLabel.Visible = 'off';
			app.kEditFieldLabel.Text = 'k';
			app.kEditField = uieditfield(app.UIFigure, 'numeric');
			app.kEditField.ValueDisplayFormat = '%11.4g [W/(m.K)]';
			app.kEditField.Visible = 'off';
			app.BoundaryConditionsListBoxLabel = uilabel(app.UIFigure);
			app.BoundaryConditionsListBoxLabel.Text = {'Boundary'; 'Conditions'};
			app.BoundaryConditionsListBox = uilistbox(app.UIFigure);
			app.BoundaryConditionsListBox.Items = {'Left', 'Right', 'Top', 'Bottom'};
			app.BoundaryConditionsListBox.ValueChangedFcn = createCallbackFcn(app, @BoundaryConditionsListBoxValueChanged, true);
			app.BoundaryConditionsListBox.Value = 'Left';
			app.InProcessLampLabel = uilabel(app.UIFigure);
			app.InProcessLampLabel.Text = 'In Process';
			app.InProcessLamp = uilamp(app.UIFigure);
			app.run_button = uibutton(app.UIFigure, 'push');
			app.run_button.ButtonPushedFcn = createCallbackFcn(app, @run_buttonButtonPushed, true);
			app.run_button.Text = 'Run';
			app.RestoreDefaultsButton = uibutton(app.UIFigure, 'push');
			app.RestoreDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultsButtonPushed, true);
			app.RestoreDefaultsButton.Text = 'Restore Defaults';
			app.T_guessEditFieldLabel = uilabel(app.UIFigure);
			app.T_guessEditFieldLabel.Text = 'T_guess';
			app.T_guessEditField = uieditfield(app.UIFigure, 'numeric');
			app.tEditFieldLabel = uilabel(app.UIFigure);
			app.tEditFieldLabel.Text = 't';
			app.tEditField = uieditfield(app.UIFigure, 'numeric');
			app.tEditField.ValueDisplayFormat = '%11.4g [m]';
			app.HeatGenEditFieldLabel = uilabel(app.UIFigure);
			app.HeatGenEditFieldLabel.Text = 'Heat Gen';
			app.HeatGenEditField = uieditfield(app.UIFigure, 'numeric');
			app.HeatGenEditField.ValueDisplayFormat = '%11.4g (W/m^3)';
			app.MaterialDropDownLabel = uilabel(app.UIFigure);
			app.MaterialDropDownLabel.Text = 'Material';
			app.MaterialDropDown = uidropdown(app.UIFigure);
			app.MaterialDropDown.Items = {'Iron', 'Copper', 'Aluminum', 'Other'};
			app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
			app.MaterialDropDown.Value = 'Iron';
			app.hEditFieldLabel = uilabel(app.UIFigure);
			app.hEditFieldLabel.Text = 'h';
			app.hEditField = uieditfield(app.UIFigure, 'numeric');
			app.MaxiterationsEditFieldLabel = uilabel(app.UIFigure);
			app.MaxiterationsEditFieldLabel.Text = 'Max iterations';
			app.MaxiterationsEditField = uieditfield(app.UIFigure, 'numeric');
			app.ExitStatusTextAreaLabel = uilabel(app.UIFigure);
			app.ExitStatusTextAreaLabel.Text = 'Exit Status';
			app.ExitStatusTextArea = uitextarea(app.UIFigure);
			app.ExitStatusTextArea.Editable = 'off';
			app.AuthorSwitch = uiswitch(app.UIFigure, 'toggle');
			app.AuthorSwitch.Items = {'Hide', 'Show Author'};
			app.AuthorSwitch.ValueChangedFcn = createCallbackFcn(app, @AuthorSwitchValueChanged, true);
			app.AuthorSwitch.Value = 'Hide';
			app.Panel = uipanel(app.UIFigure);
			app.Panel.Visible = 'off';
			app.OuzalpetinLabel = uilabel(app.Panel);
			app.OuzalpetinLabel.Text = 'Oğuzalp Çetin';
			app.MonitoringNodeLabel = uilabel(app.UIFigure);
			app.MonitoringNodeLabel.Text = 'Monitoring Node';
			app.XEditFieldLabel = uilabel(app.UIFigure);
			app.XEditFieldLabel.Text = 'X';
			app.XEditField = uieditfield(app.UIFigure, 'numeric');
			app.XEditField.ValueDisplayFormat = '%11.4g [Node Number]';
			app.YEditFieldLabel = uilabel(app.UIFigure);
			app.YEditFieldLabel.Text = 'Y';
			app.YEditField = uieditfield(app.UIFigure, 'numeric');
			app.YEditField.ValueDisplayFormat = '%11.4g [Node Number]';
			app.StopButton = uibutton(app.UIFigure, 'push');
			app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
			app.StopButton.Text = 'Stop';
			app.CurrentIterationLabel = uilabel(app.UIFigure);
			app.CurrentIterationLabel.Text = {'Current'; 'Iteration'};
			app.CurrentIterationEditField = uieditfield(app.UIFigure, 'numeric');
			app.CurrentIterationEditField.Editable = 'off';
			app.TemperatureEditFieldLabel = uilabel(app.UIFigure);
			app.TemperatureEditFieldLabel.Text = 'Temperature';
			app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
			app.TemperatureEditField.Editable = 'off';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = oguzalp_cetin

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end